classdef Practicas_de_laboratorio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InicioTab                       matlab.ui.container.Tab
        d2thetaoverdt2bovermLdthetaoverdtgoverLsintheta0Label  matlab.ui.control.Label
        MIMARCELAMONTSERRATTREJOGUERRALabel  matlab.ui.control.Label
        ParanavegaratravesdeestesimuladorsolohagaclickenlapartesuperiorseleccionandolaprcticaquedesearealizarLabel  matlab.ui.control.Label
        ElpndulosimpleconsisteenunamasapuntualmsuspendidaenunpuntofijomedianteunavarillargidadelongitudLymasadespreciableElsistemaincluyeunmecanismodefriccinviscosaconcoeficientebquemodelalasprdidasporresistenciadelmedioyfriccinenelpuntodepivoteElmovimientoestrestringidoaunplanoverticalbajolainfluenciadelagravedadgLabel  matlab.ui.control.Label
        ElsistemaestudiadoenestesimuladorcorrespondealpndulosimpleconfriccinunsistemadinmicoquerepresentaunodelosmodelosmassimplesenlateoradecontrolautomticoEstesistemaencapsulacomplejidadesmatemticasycomportamientosdinmicosnolinealescabedestacarquesibiendichanaturalezanolinealessimuladaenestapracticaestareadelalumnosercapazdeobtenerunpuntodeequilibrioenlaecuacinquedescribealsistemaconelfindeobtenersurespresentacinLTILabel  matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        UniversidadNacionalAutnomadeMxicoFacultaddeingenieraLaboratorioVirtualdeControlautomticoLabel  matlab.ui.control.Label
        Prctica1Tab                     matlab.ui.container.Tab
        PosicininicialSlider            matlab.ui.control.Slider
        PosicininicialSliderLabel       matlab.ui.control.Label
        NotaparaelalumnorecuerdequelarepresentacinobtenidaenestosgrficoscorrespondealmodelonolinealizadodelsistemaLabel  matlab.ui.control.Label
        PropiedaddeFacultaddeIngenieraUNAMLabel  matlab.ui.control.Label
        ReiniciarsimulacinButton        matlab.ui.control.Button
        SimularButton                   matlab.ui.control.Button
        FriccinEditField                matlab.ui.control.NumericEditField
        CoeficientedefriccinEditFieldLabel  matlab.ui.control.Label
        LongitudmEditField              matlab.ui.control.NumericEditField
        LongitudmEditFieldLabel         matlab.ui.control.Label
        MagnituddelafuerzaNEditField_2  matlab.ui.control.NumericEditField
        MagnituddelafuerzaNLabel        matlab.ui.control.Label
        MasakgEditField                 matlab.ui.control.NumericEditField
        MasakgEditFieldLabel            matlab.ui.control.Label
        ConlasconstantesenlamatrizdeestadomasadelpndulomlongituddelpnduloLcoeficientedefriccinbylagravedadg978kgoverms2AcontinuaciningreseelvalordelosparametroslamagnituddelafuerzaylaposicininicialdelsistemaLabel  matlab.ui.control.Label
        LTILabel                        matlab.ui.control.Label
        Prctica1ModelomatemticoLabel    matlab.ui.control.Label
        res                             matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        Prctica2Tab                     matlab.ui.container.Tab
        GananciaproporcionalEditField   matlab.ui.control.NumericEditField
        GananciaproporcionalEditFieldLabel  matlab.ui.control.Label
        DenominadordelaretroalimentacinEditField  matlab.ui.control.EditField
        DenominadordelaretroalimentacinEditFieldLabel  matlab.ui.control.Label
        NmeradordelaretroalimentacinEditField  matlab.ui.control.EditField
        NmeradordelaretroalimentacinEditFieldLabel  matlab.ui.control.Label
        ParalaposicincomosalidaThetasoverUs1overmL2overs2bovermL2sgoverLLabel  matlab.ui.control.Label
        textbfLasexpresionesquedescribenlafuncindetransferenciadelsistemateniendocomosalidaposicinyvelocidadsonlassiguientesLabel  matlab.ui.control.Label
        AnlisisporDropDown              matlab.ui.control.DropDown
        AnlisisporDropDownLabel         matlab.ui.control.Label
        SalidadelsistemaDropDown        matlab.ui.control.DropDown
        SalidadelsistemaLabel           matlab.ui.control.Label
        friccinEditField_2              matlab.ui.control.NumericEditField
        CoeficientedefriccinEditFieldLabel_2  matlab.ui.control.Label
        LongitudmEditField_2            matlab.ui.control.NumericEditField
        LongitudmEditField_2Label       matlab.ui.control.Label
        MagnituddelafuerzaNEditField    matlab.ui.control.NumericEditField
        MagnituddelafuerzaNEditFieldLabel  matlab.ui.control.Label
        MasakgEditField_2               matlab.ui.control.NumericEditField
        MasakgEditField_2Label          matlab.ui.control.Label
        AcontinuaciningreseelvalordelosparametrosylamagnituddelafuerzaLabel  matlab.ui.control.Label
        PropiedaddeFacultaddeIngenieraUNAMLabel_2  matlab.ui.control.Label
        ReiniciarButton                 matlab.ui.control.Button
        MostrarelanlisisdeestabilidadButton  matlab.ui.control.Button
        LTILabel_2                      matlab.ui.control.Label
        Prctica2AnlisisdeestabilidadLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
 app.SimularButton.Enable = 'off';
app.ReiniciarsimulacinButton.Enable = 'off';            

% Validar parámetros
if isempty(app.MasakgEditField.Value) || app.MasakgEditField.Value <= 0 || isempty(app.FriccinEditField.Value) || isempty(app.LongitudmEditField.Value) || app.LongitudmEditField.Value <= 0
    errordlg('Los parámetros m, b y L deben ser números positivos.', 'Error de parámetros');
     app.SimularButton.Enable = 'on';
     app.ReiniciarsimulacinButton.Enable = 'on';   
    return;
end

% Parámetros del péndulo
m = app.MasakgEditField.Value; % Masa en kg
L = app.LongitudmEditField.Value; % Longitud en m
b = app.FriccinEditField.Value; % Coeficiente de fricción
F = app.MagnituddelafuerzaNEditField_2.Value; % Fuerza externa en N
g = 9.78; % Aceleración debida a la gravedad
pos_inicial = app.PosicininicialSlider.Value * 2* pi /360;
% PARÁMETROS DE SIMULACIÓN CON IMPULSO INICIAL
% Condiciones iniciales fijas en 0 para posición
theta0 = pos_inicial; % Ángulo inicial SIEMPRE en 0

% La fuerza F se aplica como impulso inicial que imparte velocidad inicial
% Suponemos que la fuerza actúa durante un tiempo muy corto Δt = 0.1s
% Esto da una velocidad inicial: ω0 = (F * Δt) / (m * L)
delta_t = 0.1; % Duración del impulso en segundos
omega0 = (F * delta_t) / (m * L); % Velocidad angular inicial calculada del impulso

tspan = [0 15]; % Tiempo de simulación (s)

% Ecuación del movimiento del péndulo con fricción 
% θ'' = - (g/L) * sin(θ) - (b/(m*L²)) * θ'
pendulum_eq = @(t, y) [y(2); - (g/L) * sin(y(1)) - (b/(m*L^2)) * y(2)];

% Condiciones iniciales
y0 = [theta0; omega0];

% Resolver las ecuaciones diferenciales
[t, y] = ode45(pendulum_eq, tspan, y0);

% Crear la línea animada en el UIAxes para posición vs tiempo
hLine = animatedline(app.res, 'Color', 'b', 'LineWidth', 2);
xlabel(app.res, 'Tiempo [s]');
ylabel(app.res, 'Posición [rad]');
title(app.res, 'Respuesta del sistema');
grid(app.res, 'on');

% Limpiar el UIAxes para el péndulo
cla(app.UIAxes2);
hold(app.UIAxes2, 'on');
axis(app.UIAxes2, 'equal');
xlim(app.UIAxes2, [-L*1.2 L*1.2]);
ylim(app.UIAxes2, [-L*1.2 L*1.2]);
grid(app.UIAxes2, 'on');
title(app.UIAxes2, sprintf('Movimiento del Péndulo'));
xlabel(app.UIAxes2, 'X [m]');
ylabel(app.UIAxes2, 'Y [m]');

% Agregar información sobre condiciones iniciales e impulso
text(app.UIAxes2, -L*0.9, L*0.8, ...
    sprintf('Impulso aplicado:\nF = %.2f N\nΔt = %.2f s\nω₀ = %.2f rad/s', ...
    F, delta_t, omega0), 'FontSize', 10, 'BackgroundColor', 'white');

% Animar el movimiento del péndulo
for i = 1:length(t)
    % Calcular las posiciones del péndulo
    pendulum_x = L * sin(y(i, 1));
    pendulum_y = -L * cos(y(i, 1));

    % Graficar el péndulo
    plot(app.UIAxes2, [0 pendulum_x], [0 pendulum_y], 'r-', 'LineWidth', 2); % Cuerda
    plot(app.UIAxes2, pendulum_x, pendulum_y, 'bo', 'MarkerSize', 10, 'MarkerFaceColor', 'b'); % Masa


    % Indicador de la velocidad inicial 
    if i == 1 && F ~= 0
        % Calcular dirección de la velocidad inicial (tangente en θ=0)
        % La velocidad es tangencial: dirección horizontal para θ=0
        vel_scale = min(L/2, 0.5); % Escala para visualización
        if omega0 > 0
            % Velocidad positiva (antihoraria)
            quiver(app.UIAxes2, pendulum_x, pendulum_y, vel_scale, 0, ...
                   'MaxHeadSize', 0.5, 'LineWidth', 2, 'Color', 'g');
        else
            % Velocidad negativa (horaria)
            quiver(app.UIAxes2, pendulum_x, pendulum_y, -vel_scale, 0, ...
                   'MaxHeadSize', 0.5, 'LineWidth', 2, 'Color', 'g');
        end
    end

    % Agregar el punto actual a la línea animada
    addpoints(hLine, t(i), y(i, 1));

    % Actualizar la gráfica
    drawnow;
    pause(0.05);

    % Limpiar para la siguiente posición
    if i < length(t)
        cla(app.UIAxes2);
        hold(app.UIAxes2, 'on');
        % Volver a dibujar información y vertical
        text(app.UIAxes2, -L*0.9, L*0.8, ...
            sprintf('Impulso aplicado:\nF = %.2f N\nΔt = %.2f s\nω₀ = %.2f rad/s', ...
            F, delta_t, omega0), 'FontSize', 10, 'BackgroundColor', 'white');
    end
end

% Agregar información final en la gráfica de posición
text(app.res, 0.05, 0.95, ...
    sprintf('Impulso: %.2f N·s\nVel. inicial: %.2f rad/s', F*delta_t, omega0), ...
    'Units', 'normalized', 'FontSize', 10, 'BackgroundColor', 'white');

% Agregar línea horizontal en 0 para referencia
yline(app.res, 0, 'r--', 'Alpha', 0.5, 'LineWidth', 1);

app.SimularButton.Enable = 'on';
app.ReiniciarsimulacinButton.Enable = 'on';
        end

        % Button pushed function: ReiniciarsimulacinButton
        function ReiniciarsimulacinButtonPushed(app, event)
            app.MasakgEditField.Value = 0; % Restablecer masa
            app.LongitudmEditField.Value = 0; % Restablecer longitud
            app.FriccinEditField.Value = 0; % Restablecer fricción
            app.MagnituddelafuerzaNEditField_2.Value=0;
            cla(app.res); % Limpiar gráfico de posición vs tiempo
            cla(app.UIAxes2); % Limpiar gráfico del péndulo 
        end

        % Button pushed function: MostrarelanlisisdeestabilidadButton
        function MostrarelanlisisdeestabilidadButtonPushed(app, event)
            
   app.MostrarelanlisisdeestabilidadButton.Enable = 'off';
    app.ReiniciarButton.Enable = 'off';      
    
    % --- 1. OBTENCIÓN DE PARÁMETROS DEL PÉNDULO ---
    m = app.MasakgEditField_2.Value;       
    L = app.LongitudmEditField_2.Value;
    b = app.friccinEditField_2.Value;
    u_mag = app.MagnituddelafuerzaNEditField_2.Value; 
    g = 9.78;
    kp= app.GananciaproporcionalEditField.Value;
    % Validar que no haya divisiones por cero
    if m <= 0 || L <= 0
        errordlg('La masa y la longitud deben ser mayores a 0.', 'Error');
        app.MostrarelanlisisdeestabilidadButton.Enable = 'on';
        app.ReiniciarButton.Enable = 'on';
        return;
    end
    
    % --- 2. OBTENCIÓN DE LA RETROALIMENTACIÓN H(s) ---
    % Leer el texto ingresado por el usuario 
    str_num_H = app.NmeradordelaretroalimentacinEditField.Value;
    str_den_H = app.DenominadordelaretroalimentacinEditField.Value;
    
    % Convertir la cadena de texto a vector numérico (ej. "1 2" se vuelve [1 2])
    num_H = str2num(str_num_H);
    den_H = str2num(str_den_H);
    
    % Validar que el usuario haya escrito vectores válidos
    if isempty(num_H) || isempty(den_H) || num_H == 0 || den_H == 0
        errordlg('Ingrese vectores válidos separados por espacios (ej. 1 2 1) y mayores a 0.', 'Error de Retroalimentación');
        app.MostrarelanlisisdeestabilidadButton.Enable = 'on';
        app.ReiniciarButton.Enable = 'on';
        return;
    end
    
    % Crear la Función de Transferencia de la Retroalimentación
    H = tf(num_H, den_H);

    % --- 3. DEFINIR PLANTA G(s) SEGÚN LA SALIDA ELEGIDA ---
    tipo_salida = app.SalidadelsistemaDropDown.Value; 
    
    % Denominador común
    den_G = [1,  b/(m*L^2),  g/L];
    num_G = [];
    
    if strcmpi(tipo_salida, 'Posición') || strcmpi(tipo_salida, 'Posición ')
        num_G = [1/(m*L^2)];
    elseif strcmpi(tipo_salida, 'Velocidad')
        num_G = [1/(m*L^2), 0];
    else
        errordlg('Opción de salida no válida.', 'Error');
        app.MostrarelanlisisdeestabilidadButton.Enable = 'on';
        app.ReiniciarButton.Enable = 'on';
        return;
    end
    
    % Crear la Función de Transferencia de la Planta
    G = tf(num_G, den_G);
    
    % --- 4. SISTEMA A EVALUAR L(s) = G(s)H(s) ---
    % Multiplicamos la planta por la retroalimentación
    Sys_eval = G * H * kp;

    % --- 5. SELECCIONAR TIPO DE ANÁLISIS ---
    tipo_analisis = app.AnlisisporDropDown.Value; 
    
    switch tipo_analisis
        case 'LGR'
            figure('Name', ['Lugar de las Raíces - Salida: ' tipo_salida]);
            rlocus(Sys_eval); 
            grid on;
            title(['LGR - Salida: ' tipo_salida]);
            
        case 'Diagrama de Bode'
            figure('Name', ['Diagrama de Bode - Salida: ' tipo_salida]);
            bode(Sys_eval);
            grid on;
            margin(Sys_eval); 
            
        case 'Criterio de Nyquist'
            figure('Name', ['Diagrama de Nyquist - Salida: ' tipo_salida]);
            nyquist(Sys_eval);
            grid on;
    end
   
    app.MostrarelanlisisdeestabilidadButton.Enable =  'on';
    app.ReiniciarButton.Enable  =  'on';
        end

        % Button pushed function: ReiniciarButton
        function ReiniciarButtonPushed(app, event)
            app.MasakgEditField_2.Value = 0; % Restablecer masa
            app.LongitudmEditField_2.Value = 0; % Restablecer longitud
            app.friccinEditField_2.Value = 0; % Restablecer fricción
            app.MagnituddelafuerzaNEditField.Value=0; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InicioTab = uitab(app.TabGroup);
            app.InicioTab.Title = 'Inicio';
            app.UniversidadNacionalAutnomadeMxicoFacultaddeingenieraLaboratorioVirtualdeControlautomticoLabel = uilabel(app.InicioTab);
            app.UniversidadNacionalAutnomadeMxicoFacultaddeingenieraLaboratorioVirtualdeControlautomticoLabel.Interpreter = 'latex';
            app.UniversidadNacionalAutnomadeMxicoFacultaddeingenieraLaboratorioVirtualdeControlautomticoLabel.Text = {'Universidad Nacional Autónoma de México '; ''; 'Facultad de ingeniería '; ''; 'Laboratorio Virtual de Control automático '};
            app.Image = uiimage(app.InicioTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'escudounam_negro.jpg');
            app.Image2 = uiimage(app.InicioTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'escudofi_negro.jpg');
            app.Image3 = uiimage(app.InicioTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2026-01-21 131252.png');
            app.ElsistemaestudiadoenestesimuladorcorrespondealpndulosimpleconfriccinunsistemadinmicoquerepresentaunodelosmodelosmassimplesenlateoradecontrolautomticoEstesistemaencapsulacomplejidadesmatemticasycomportamientosdinmicosnolinealescabedestacarquesibiendichanaturalezanolinealessimuladaenestapracticaestareadelalumnosercapazdeobtenerunpuntodeequilibrioenlaecuacinquedescribealsistemaconelfindeobtenersurespresentacinLTILabel = uilabel(app.InicioTab);
            app.ElsistemaestudiadoenestesimuladorcorrespondealpndulosimpleconfriccinunsistemadinmicoquerepresentaunodelosmodelosmassimplesenlateoradecontrolautomticoEstesistemaencapsulacomplejidadesmatemticasycomportamientosdinmicosnolinealescabedestacarquesibiendichanaturalezanolinealessimuladaenestapracticaestareadelalumnosercapazdeobtenerunpuntodeequilibrioenlaecuacinquedescribealsistemaconelfindeobtenersurespresentacinLTILabel.Text = {'El sistema estudiado en este simulador corresponde al péndulo simple con fricción, un sistema dinámico '; 'que representa uno de los modelos mas simples en la teoría de control automático. '; 'Este sistema, encapsula complejidades matemáticas y comportamientos dinámicos no lineales, '; 'cabe destacar que si bien dicha naturaleza no lineal es simulada en esta practica; '; 'es tarea del alumno ser capaz de obtener un punto de equilibrio en la ecuación que '; 'describe al sistema con el fin de obtener su respresentación LTI '; ''};
            app.ElpndulosimpleconsisteenunamasapuntualmsuspendidaenunpuntofijomedianteunavarillargidadelongitudLymasadespreciableElsistemaincluyeunmecanismodefriccinviscosaconcoeficientebquemodelalasprdidasporresistenciadelmedioyfriccinenelpuntodepivoteElmovimientoestrestringidoaunplanoverticalbajolainfluenciadelagravedadgLabel = uilabel(app.InicioTab);
            app.ElpndulosimpleconsisteenunamasapuntualmsuspendidaenunpuntofijomedianteunavarillargidadelongitudLymasadespreciableElsistemaincluyeunmecanismodefriccinviscosaconcoeficientebquemodelalasprdidasporresistenciadelmedioyfriccinenelpuntodepivoteElmovimientoestrestringidoaunplanoverticalbajolainfluenciadelagravedadgLabel.Text = {'El péndulo simple consiste en una masa puntual (m) suspendida en un punto fijo mediante una varilla rígida de longitud '; '(L) y masa despreciable. El sistema incluye un mecanismo de fricción viscosa con coeficiente '; '(b) que modela las pérdidas por resistencia del medio y fricción en el punto de pivote.'; 'El movimiento está restringido a un plano vertical bajo la influencia de la gravedad g.'};
            app.ParanavegaratravesdeestesimuladorsolohagaclickenlapartesuperiorseleccionandolaprcticaquedesearealizarLabel = uilabel(app.InicioTab);
            app.ParanavegaratravesdeestesimuladorsolohagaclickenlapartesuperiorseleccionandolaprcticaquedesearealizarLabel.Text = 'Para navegar a traves de este simulador solo haga click en la parte superior seleccionando la práctica que desea realizar';
            app.MIMARCELAMONTSERRATTREJOGUERRALabel = uilabel(app.InicioTab);
            app.MIMARCELAMONTSERRATTREJOGUERRALabel.Interpreter = 'latex';
            app.MIMARCELAMONTSERRATTREJOGUERRALabel.Text = 'M.I. MARCELA MONTSERRAT TREJO GUERRA';
            app.d2thetaoverdt2bovermLdthetaoverdtgoverLsintheta0Label = uilabel(app.InicioTab);
            app.d2thetaoverdt2bovermLdthetaoverdtgoverLsintheta0Label.Interpreter = 'latex';
            app.d2thetaoverdt2bovermLdthetaoverdtgoverLsintheta0Label.Text = '$ {{d^2 \theta} \over {dt^2} }+ {{b} \over {mL}} {{d \theta} \over {dt}} + {g \over L}  sin( \theta)=0';
            app.Prctica1Tab = uitab(app.TabGroup);
            app.Prctica1Tab.Title = 'Práctica 1';
            app.UIAxes2 = uiaxes(app.Prctica1Tab);
            title(app.UIAxes2, 'Movimiento del péndulo ')
            xlabel(app.UIAxes2, 'X [m]')
            ylabel(app.UIAxes2, 'Y [m]')
            zlabel(app.UIAxes2, 'Z')
            app.res = uiaxes(app.Prctica1Tab);
            title(app.res, 'Respuesta del sistema')
            xlabel(app.res, 'Tiempo [s]')
            ylabel(app.res, 'Posición [rad]')
            zlabel(app.res, 'Z')
            app.Prctica1ModelomatemticoLabel = uilabel(app.Prctica1Tab);
            app.Prctica1ModelomatemticoLabel.Interpreter = 'latex';
            app.Prctica1ModelomatemticoLabel.Text = {'Práctica 1 '; 'Modelo matemático '};
            app.LTILabel = uilabel(app.Prctica1Tab);
            app.LTILabel.Interpreter = 'latex';
            app.LTILabel.Text = {'\textbf{Representación LTI}'; ''; 'El sistema de ecuaciones:'; ''; '$\dot{x}=Ax+Bu'; ''; '$y=Cx+Du'; ''; 'Cuya representación matricial es:'; ''; '$\pmatrix{\dot{x_1}\cr \dot{x_2}}=\pmatrix{0 & 1 \cr -g \over L & -b \over mL^2}\pmatrix{x_1\cr x_2}+\pmatrix{0 \cr 1\over mL^2}u$'; ''; '$y=\pmatrix{1 & 0}\pmatrix{x_1 \cr x_2}$'};
            app.ConlasconstantesenlamatrizdeestadomasadelpndulomlongituddelpnduloLcoeficientedefriccinbylagravedadg978kgoverms2AcontinuaciningreseelvalordelosparametroslamagnituddelafuerzaylaposicininicialdelsistemaLabel = uilabel(app.Prctica1Tab);
            app.ConlasconstantesenlamatrizdeestadomasadelpndulomlongituddelpnduloLcoeficientedefriccinbylagravedadg978kgoverms2AcontinuaciningreseelvalordelosparametroslamagnituddelafuerzaylaposicininicialdelsistemaLabel.Interpreter = 'latex';
            app.ConlasconstantesenlamatrizdeestadomasadelpndulomlongituddelpnduloLcoeficientedefriccinbylagravedadg978kgoverms2AcontinuaciningreseelvalordelosparametroslamagnituddelafuerzaylaposicininicialdelsistemaLabel.Text = {'Con las constantes en la matriz de estado:  '; 'masa del péndulo ($m)'; 'longitud del péndulo ($L)'; 'coeficiente de fricción ($b),'; 'y la gravedad ($g=9.78 [{kg\over ms^2}]$ )'; ''; 'A continuación, ingrese el valor de los parametros, la magnitud de la fuerza '; 'y la posición inicial del sistema'};
            app.MasakgEditFieldLabel = uilabel(app.Prctica1Tab);
            app.MasakgEditFieldLabel.Text = 'Masa [kg]';
            app.MasakgEditField = uieditfield(app.Prctica1Tab, 'numeric');
            app.MasakgEditField.Limits = [0 100];
            app.MagnituddelafuerzaNLabel = uilabel(app.Prctica1Tab);
            app.MagnituddelafuerzaNLabel.Text = 'Magnitud de la fuerza [N]';
            app.MagnituddelafuerzaNEditField_2 = uieditfield(app.Prctica1Tab, 'numeric');
            app.MagnituddelafuerzaNEditField_2.Limits = [-100 100];
            app.LongitudmEditFieldLabel = uilabel(app.Prctica1Tab);
            app.LongitudmEditFieldLabel.Text = 'Longitud [m]';
            app.LongitudmEditField = uieditfield(app.Prctica1Tab, 'numeric');
            app.LongitudmEditField.Limits = [0 100];
            app.CoeficientedefriccinEditFieldLabel = uilabel(app.Prctica1Tab);
            app.CoeficientedefriccinEditFieldLabel.Text = 'Coeficiente de fricción';
            app.FriccinEditField = uieditfield(app.Prctica1Tab, 'numeric');
            app.FriccinEditField.Limits = [0 100];
            app.SimularButton = uibutton(app.Prctica1Tab, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.ReiniciarsimulacinButton = uibutton(app.Prctica1Tab, 'push');
            app.ReiniciarsimulacinButton.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarsimulacinButtonPushed, true);
            app.ReiniciarsimulacinButton.Text = 'Reiniciar simulación';
            app.PropiedaddeFacultaddeIngenieraUNAMLabel = uilabel(app.Prctica1Tab);
            app.PropiedaddeFacultaddeIngenieraUNAMLabel.Text = 'Propiedad de Facultad de Ingeniería UNAM ';
            app.NotaparaelalumnorecuerdequelarepresentacinobtenidaenestosgrficoscorrespondealmodelonolinealizadodelsistemaLabel = uilabel(app.Prctica1Tab);
            app.NotaparaelalumnorecuerdequelarepresentacinobtenidaenestosgrficoscorrespondealmodelonolinealizadodelsistemaLabel.Text = {'Nota para el alumno: recuerde que la representación obtenida en estos gráficos '; 'corresponde al modelo no linealizado del sistema'};
            app.PosicininicialSliderLabel = uilabel(app.Prctica1Tab);
            app.PosicininicialSliderLabel.Text = 'Posición inicial [°]';
            app.PosicininicialSlider = uislider(app.Prctica1Tab);
            app.PosicininicialSlider.Limits = [0 360];
            app.Prctica2Tab = uitab(app.TabGroup);
            app.Prctica2Tab.Title = 'Práctica 2';
            app.Prctica2AnlisisdeestabilidadLabel = uilabel(app.Prctica2Tab);
            app.Prctica2AnlisisdeestabilidadLabel.Interpreter = 'latex';
            app.Prctica2AnlisisdeestabilidadLabel.Text = {'Práctica 2'; 'Análisis de estabilidad '};
            app.LTILabel_2 = uilabel(app.Prctica2Tab);
            app.LTILabel_2.Interpreter = 'latex';
            app.LTILabel_2.Text = {'Para la velocidad como salida:'; '${{\Omega(s) \over U(s)}={{1 \over mL^2} s \over s^2 + {b \over mL^2}s + {g \over L}}'};
            app.MostrarelanlisisdeestabilidadButton = uibutton(app.Prctica2Tab, 'push');
            app.MostrarelanlisisdeestabilidadButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarelanlisisdeestabilidadButtonPushed, true);
            app.MostrarelanlisisdeestabilidadButton.Text = 'Mostrar el análisis de estabilidad';
            app.ReiniciarButton = uibutton(app.Prctica2Tab, 'push');
            app.ReiniciarButton.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButtonPushed, true);
            app.ReiniciarButton.Text = 'Reiniciar ';
            app.PropiedaddeFacultaddeIngenieraUNAMLabel_2 = uilabel(app.Prctica2Tab);
            app.PropiedaddeFacultaddeIngenieraUNAMLabel_2.Text = 'Propiedad de Facultad de Ingeniería UNAM ';
            app.AcontinuaciningreseelvalordelosparametrosylamagnituddelafuerzaLabel = uilabel(app.Prctica2Tab);
            app.AcontinuaciningreseelvalordelosparametrosylamagnituddelafuerzaLabel.Interpreter = 'latex';
            app.AcontinuaciningreseelvalordelosparametrosylamagnituddelafuerzaLabel.Text = 'A continuación, ingrese el valor de los parametros y la magnitud de la fuerza';
            app.MasakgEditField_2Label = uilabel(app.Prctica2Tab);
            app.MasakgEditField_2Label.Text = 'Masa [kg]';
            app.MasakgEditField_2 = uieditfield(app.Prctica2Tab, 'numeric');
            app.MasakgEditField_2.Limits = [0 100];
            app.MagnituddelafuerzaNEditFieldLabel = uilabel(app.Prctica2Tab);
            app.MagnituddelafuerzaNEditFieldLabel.Text = 'Magnitud de la fuerza [N]';
            app.MagnituddelafuerzaNEditField = uieditfield(app.Prctica2Tab, 'numeric');
            app.MagnituddelafuerzaNEditField.Limits = [-100 100];
            app.LongitudmEditField_2Label = uilabel(app.Prctica2Tab);
            app.LongitudmEditField_2Label.Text = 'Longitud [m]';
            app.LongitudmEditField_2 = uieditfield(app.Prctica2Tab, 'numeric');
            app.LongitudmEditField_2.Limits = [0 100];
            app.CoeficientedefriccinEditFieldLabel_2 = uilabel(app.Prctica2Tab);
            app.CoeficientedefriccinEditFieldLabel_2.Text = 'Coeficiente de fricción';
            app.friccinEditField_2 = uieditfield(app.Prctica2Tab, 'numeric');
            app.friccinEditField_2.Limits = [0 100];
            app.SalidadelsistemaLabel = uilabel(app.Prctica2Tab);
            app.SalidadelsistemaLabel.Text = 'Salida del sistema:';
            app.SalidadelsistemaDropDown = uidropdown(app.Prctica2Tab);
            app.SalidadelsistemaDropDown.Items = {'Posición ', 'Velocidad'};
            app.SalidadelsistemaDropDown.Value = 'Posición ';
            app.AnlisisporDropDownLabel = uilabel(app.Prctica2Tab);
            app.AnlisisporDropDownLabel.Text = 'Análisis por:';
            app.AnlisisporDropDown = uidropdown(app.Prctica2Tab);
            app.AnlisisporDropDown.Items = {'LGR', 'Diagrama de Bode', 'Criterio de Nyquist'};
            app.AnlisisporDropDown.Value = 'LGR';
            app.textbfLasexpresionesquedescribenlafuncindetransferenciadelsistemateniendocomosalidaposicinyvelocidadsonlassiguientesLabel = uilabel(app.Prctica2Tab);
            app.textbfLasexpresionesquedescribenlafuncindetransferenciadelsistemateniendocomosalidaposicinyvelocidadsonlassiguientesLabel.Interpreter = 'latex';
            app.textbfLasexpresionesquedescribenlafuncindetransferenciadelsistemateniendocomosalidaposicinyvelocidadsonlassiguientesLabel.Text = '\textbf{Las expresiones que describen la función de transferencia del sistema, teniendo como salida posición y velocidad, son las siguientes:}';
            app.ParalaposicincomosalidaThetasoverUs1overmL2overs2bovermL2sgoverLLabel = uilabel(app.Prctica2Tab);
            app.ParalaposicincomosalidaThetasoverUs1overmL2overs2bovermL2sgoverLLabel.Interpreter = 'latex';
            app.ParalaposicincomosalidaThetasoverUs1overmL2overs2bovermL2sgoverLLabel.Text = {'Para la posición como salida:'; '${{\Theta(s) \over U(s)}={{1 \over mL^2} \over s^2 + {b \over mL^2}s + {g \over L}}'};
            app.NmeradordelaretroalimentacinEditFieldLabel = uilabel(app.Prctica2Tab);
            app.NmeradordelaretroalimentacinEditFieldLabel.Text = 'Númerador de la retroalimentación';
            app.NmeradordelaretroalimentacinEditField = uieditfield(app.Prctica2Tab, 'text');
            app.NmeradordelaretroalimentacinEditField.Value = '1';
            app.DenominadordelaretroalimentacinEditFieldLabel = uilabel(app.Prctica2Tab);
            app.DenominadordelaretroalimentacinEditFieldLabel.Text = 'Denominador de la retroalimentación';
            app.DenominadordelaretroalimentacinEditField = uieditfield(app.Prctica2Tab, 'text');
            app.DenominadordelaretroalimentacinEditField.Value = '1';
            app.GananciaproporcionalEditFieldLabel = uilabel(app.Prctica2Tab);
            app.GananciaproporcionalEditFieldLabel.Text = 'Ganancia proporcional';
            app.GananciaproporcionalEditField = uieditfield(app.Prctica2Tab, 'numeric');
            app.GananciaproporcionalEditField.Limits = [1 Inf];
            app.GananciaproporcionalEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Practicas_de_laboratorio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end