classdef Practicas_de_motorDC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        InicioTab                      matlab.ui.container.Tab
        EnestesimuladorpodrsconocerladinamicadelsistemaenlazoabiertolazocerradoydisearuncontroladorLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        ParanavegaratravesdeestesimuladorsolohagaclickenlapartesuperiorseleccionandolaprcticaquedesearealizarLabel  matlab.ui.control.Label
        ElsistemaestudiadoenestesimuladorcorrespondealmotordecorrientecontinuaUnsistemahibridocompuestoporunsistemamecanicoyunelectricoampliamenteestudiadoenlateoradecontrolendondeReslaresistenciadelaarmaduraLlainductanciadelaarmaduraJelmomentodeinerciadelrotorbelcoeficientedefriccinviscosak_tconstantedetorquek_econstantedefuerzacontraelectromotrizLabel  matlab.ui.control.Label
        Image3                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        UniversidadNacionalAutnomadeMxicoFacultaddeingenieraLaboratorioVirtualdeControlautomticoLabel  matlab.ui.control.Label
        Prctica1Tab                    matlab.ui.container.Tab
        Jkgm2EditField                 matlab.ui.control.NumericEditField
        Jkgm2Label                     matlab.ui.control.Label
        k_mNmoverAEditField            matlab.ui.control.NumericEditField
        k_mNmoverALabel                matlab.ui.control.Label
        k_eradoverVsEditField          matlab.ui.control.NumericEditField
        k_eradoverVsLabel              matlab.ui.control.Label
        PropiedaddeFacultaddeIngenieraUNAMLabel  matlab.ui.control.Label
        ReiniciarsimulacinButton       matlab.ui.control.Button
        SimularButton                  matlab.ui.control.Button
        FriccinEditField               matlab.ui.control.NumericEditField
        BLabel                         matlab.ui.control.Label
        LHEditField                    matlab.ui.control.NumericEditField
        LHEditFieldLabel               matlab.ui.control.Label
        MagnituddelvoltajeVEditField   matlab.ui.control.NumericEditField
        MagnituddelafuerzaNLabel       matlab.ui.control.Label
        ROmegaEditField                matlab.ui.control.NumericEditField
        ROmegaLabel                    matlab.ui.control.Label
        ConlasconstantesResistenciadelemboninadoRInductanciadearmaduraLCoeficientedefriccinviscosabConstantedefuerzacontraelectromotrizk_eConstantedetorqueelectromecnicok_tylaconstantedeinerciaJAcontinuaciningreseelvalordelosparametrosylamagnituddelvoltajeLabel  matlab.ui.control.Label
        LTILabel                       matlab.ui.control.Label
        Prctica1ModelomatemticoLabel   matlab.ui.control.Label
        res                            matlab.ui.control.UIAxes
        Prctica23Tab                   matlab.ui.container.Tab
        DenominadordelaretroalimentacinEditField_2  matlab.ui.control.EditField
        DenominadordelaretroalimentacinEditField_2Label  matlab.ui.control.Label
        NmeradordelaretroalimentacinEditField_2  matlab.ui.control.EditField
        NmeradordelaretroalimentacinEditField_2Label  matlab.ui.control.Label
        Jkgm2EditField_2               matlab.ui.control.NumericEditField
        Jkgm2EditField_2Label          matlab.ui.control.Label
        k_mNmoverAEditField_2          matlab.ui.control.NumericEditField
        k_mNmoverAEditField_2Label     matlab.ui.control.Label
        k_eradoverVsEditField_2        matlab.ui.control.NumericEditField
        k_eradoverVsEditField_2Label   matlab.ui.control.Label
        FriccinEditField_2             matlab.ui.control.NumericEditField
        BLabel_2                       matlab.ui.control.Label
        LHEditField_2                  matlab.ui.control.NumericEditField
        LHEditField_2Label             matlab.ui.control.Label
        ROmegaEditField_2              matlab.ui.control.NumericEditField
        ROmegaEditField_2Label         matlab.ui.control.Label
        GananciaproporcionalEditField  matlab.ui.control.NumericEditField
        GananciaproporcionalEditFieldLabel  matlab.ui.control.Label
        ParalaposicincomosalidaThetasoverUsk_eoversRLsJsbk_ek_tLabel  matlab.ui.control.Label
        textbfLasexpresionesquedescribenlafuncindetransferenciadelsistemateniendocomosalidaposicinyvelocidadsonlassiguientesLabel  matlab.ui.control.Label
        AnlisisporDropDown             matlab.ui.control.DropDown
        AnlisisporDropDownLabel        matlab.ui.control.Label
        SalidadelsistemaDropDown       matlab.ui.control.DropDown
        SalidadelsistemaLabel          matlab.ui.control.Label
        MagnituddelVoltajeVEditField   matlab.ui.control.NumericEditField
        MagnituddelVoltajeVEditFieldLabel  matlab.ui.control.Label
        AcontinuaciningreseelvalordelosparametrosylamagnituddelvoltajeLabel  matlab.ui.control.Label
        PropiedaddeFacultaddeIngenieraUNAMLabel_2  matlab.ui.control.Label
        ReiniciarButton                matlab.ui.control.Button
        MostrarelanlisisdeestabilidadButton  matlab.ui.control.Button
        LTILabel_2                     matlab.ui.control.Label
        Prctica23AnlisisdeestabilidadLabel  matlab.ui.control.Label
        Prctica4Tab                    matlab.ui.container.Tab
        PropiedaddeFacultaddeIngenieraUNAMLabel_3  matlab.ui.control.Label
        VelocidaddeseadaradsEditField  matlab.ui.control.NumericEditField
        VelocidaddeseadaradsEditFieldLabel  matlab.ui.control.Label
        AcontinuacinpuedesdiseartucontroladoryobservarlarespuestadelsistemaLabel  matlab.ui.control.Label
        OmegasoverUsk_eoverRJoversk_ek_toverRJLabel  matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        k_e088671k_t041675J001639R6Label  matlab.ui.control.Label
        ElmotorDCbajoestudioenestaprcticaeselGM25370HasidocaracterizadoexperimentalmentedespreciandolainductanciaylafriccinobteniendolossiguientesparametrosylafuncindetransferenciaLabel  matlab.ui.control.Label
        ReiniciarButton_2              matlab.ui.control.Button
        SimularcontrolButton           matlab.ui.control.Button
        K_dEditField                   matlab.ui.control.NumericEditField
        K_dLabel                       matlab.ui.control.Label
        K_iEditField                   matlab.ui.control.NumericEditField
        K_iLabel                       matlab.ui.control.Label
        K_pEditField                   matlab.ui.control.NumericEditField
        K_pLabel                       matlab.ui.control.Label
        Prctica4DiseodecontroladoresPIDLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
app.SimularButton.Enable = 'off';
    app.ReiniciarsimulacinButton.Enable = 'off';            
    
    % --- 1. OBTENCIÓN DE PARÁMETROS DEL MOTOR DC ---
    R  = app.ROmegaEditField.Value;         % Resistencia de armadura [Ohms]
    La = app.LHEditField.Value;             % Inductancia de armadura [H]
    B  = app.FriccinEditField.Value;              % Coeficiente de fricción viscosa [N*m*s/rad]
    ke = app.k_eradoverVsEditField.Value;        % Constante contraelectromotriz [V*s/rad]
    km = app.k_mNmoverAEditField.Value;          % Constante de torque [N*m/A]
    J  = app.Jkgm2EditField.Value;          % Momento de inercia [kg*m^2]
    V_in = app.MagnituddelvoltajeVEditField.Value; % Voltaje de entrada (Magnitud del escalón)
    
    % Validar parámetros críticos para evitar división por cero
    if R <= 0 || ke <=0 || km <=0 || J<=0 || La <= 0 || J<=0
        errordlg('Los parámetros deben ser estrictamente positivos.', 'Error de parámetros');
        app.SimularButton.Enable = 'on';
        app.ReiniciarsimulacinButton.Enable = 'on';
        return;
    end

    % --- 2. DEFINIR MODELO EN ESPACIO DE ESTADOS ---
    % x = [corriente (i); velocidad_angular (w)]
    % dx/dt = Ax + Bu
    % y = Cx + Du (Salida: velocidad angular)
    
    A = [-R/La, -ke/La; 
          km/J,  -B/J];
          
    B_mat = [1/La; 
             0];
             
    C = [0, 1]; % Queremos ver la velocidad (el segundo estado)
    
    D = 0;
    
    % Crear el sistema LTI en MATLAB
    sys_dc = ss(A, B_mat, C, D);
    
    % --- 3. CONFIGURACIÓN DE LA SIMULACIÓN ---
    sys_escalado=V_in*sys_dc;


    % Simular la respuesta del sistema lineal
    [y_out, t_out, x_out] = step(sys_escalado);

    % --- 4. GRAFICAR RESULTADOS ---
    % Limpiar el eje antes de graficar (asumo que tu eje se llama app.res)
    cla(app.res);
    hold(app.res, 'on');
    
    % Graficar la velocidad angular
    plot(app.res, t_out, y_out, 'b-', 'LineWidth', 2);
    
    % Línea de referencia del voltaje de entrada (opcional, escalada para verla)
    % plot(app.res, t, u, 'r--', 'LineWidth', 1); 
    
    % Configurar la gráfica
    grid(app.res, 'on');
    title(app.res, sprintf('Respuesta de Velocidad del Motor DC a V = %.1f [V]', V_in));
    xlabel(app.res, 'Tiempo [s]');
    ylabel(app.res, 'Velocidad Angular \omega [rad/s]');
    
    % Calcular y mostrar el valor de estado estacionario
    vel_ss = y_out(end);
    
    % Etiqueta con información
    texto_info = sprintf('Vel. Estacionaria: %.2f rad/s\nVoltaje: %.1f V', vel_ss, V_in);
    
    % Posicionar el texto dinámicamente en la esquina inferior derecha
    % Extraemos el último elemento de t_out para tener un único número
    x_pos = t_out(end) * 0.6; 
    
    % y_pos ya es un número único
    y_pos = vel_ss * 0.2; 
    
    % Dibujar el texto en las coordenadas escalares
    text(app.res, x_pos, y_pos, texto_info, 'FontSize', 10, 'BackgroundColor', 'w', 'EdgeColor', 'k');
    
    hold(app.res, 'off');
    
    % --- 5. REACTIVAR BOTONES ---
    app.SimularButton.Enable = 'on';
    app.ReiniciarsimulacinButton.Enable = 'on';
        end

        % Button pushed function: ReiniciarsimulacinButton
        function ReiniciarsimulacinButtonPushed(app, event)
            app.ROmegaEditField.Value = 0; % Restablecer masa
            app.LHEditField.Value = 0; % Restablecer longitud
            app.FriccinEditField.Value = 0; % Restablecer fricción
            app.MagnituddelvoltajeVEditField.Value=0;
            app.k_eradoverVsEditField.Value=0;
            app.k_mNmoverAEditField.Value=0;
            app.Jkgm2EditField.Value=0;
            cla(app.res); % Limpiar gráfico de posición vs tiempo
            
        end

        % Button pushed function: MostrarelanlisisdeestabilidadButton
        function MostrarelanlisisdeestabilidadButtonPushed(app, event)
            
    app.MostrarelanlisisdeestabilidadButton.Enable = 'off';
    app.ReiniciarButton.Enable = 'off';      
% --- 1. OBTENCIÓN DE PARÁMETROS ---
    R = app.ROmegaEditField_2.Value;
    La= app.LHEditField_2.Value;
    B= app.FriccinEditField_2.Value;
    ke = app.k_eradoverVsEditField_2.Value;
    km = app.k_mNmoverAEditField_2.Value;
    J= app.Jkgm2EditField_2.Value;
    u_mag = app.MagnituddelvoltajeVEditField.Value; % Aunque no afecta estabilidad, lo leemos

    % Validar que no haya divisiones por cero
    if R <= 0 || J <= 0 || ke <= 0 || km <= 0
        errordlg('R, J, ke y kt deben ser mayores a 0.', 'Error');
        app.MostrarelanlisisdeestabilidadButton.Enable = 'on';
        app.ReiniciarButton.Enable = 'on';
        return;
    end
    
    %Retroalimentación
    kp=app.GananciaproporcionalEditField.Value;
    str_num_H=app.NmeradordelaretroalimentacinEditField_2.Value;
    str_den_H = app.DenominadordelaretroalimentacinEditField_2.Value;

    num_H = str2num(str_num_H);
    den_H = str2num(str_den_H);
    
    if isempty(num_H) || isempty(den_H)
        errordlg('Ingrese vectores válidos para la retroalimentación (ej. 1 2 1).', 'Error');
        app.MostrarelanlisisdeestabilidadButton.Enable = 'on';
        app.ReiniciarButton.Enable = 'on';
        return;
    end

    H= tf(num_H, den_H);

    % --- 2. DEFINIR NUMERADOR SEGÚN LA SALIDA ELEGIDA ---
    % Leemos el valor del DropDown "Salida del sistema"
    tipo_salida = app.SalidadelsistemaDropDown.Value; 
    
    % Calculamos los coeficientes normalizados para coincidir con la IMAGEN
    % numerador común
    num_G = km;
    
    if strcmp(tipo_salida, 'Posición ')
        
        den_G = [J*La, (La*B + R*J), (R*B + ke*km), 0];
        
    elseif strcmp(tipo_salida, 'Velocidad')
        
        den_G = [J*La, (La*B + R*J), (R*B + ke*km)];
    end
    
    % Crear la Función de Transferencia
    G = tf(num_G, den_G);
    %Definición del sistema a evaluar
    Sys_eval = G * H * kp;
    % --- 3. SELECCIONAR TIPO DE ANÁLISIS ---
    % Leemos el valor del DropDown "Analisis por"
    tipo_analisis = app.AnlisisporDropDown.Value; 
    
    % Limpiamos o preparamos la gráfica
    % NOTA: Si usas ejes incrustados (UIAxes), usa cla(app.UIAxes);
    % Si prefieres ventanas emergentes (como acordamos antes), usa figure:
    
    switch tipo_analisis
        case 'LGR'
            figure('Name', ['Lugar de las Raíces - Salida: ' tipo_salida]);
            rlocus(Sys_eval);
            grid on;
            title(['LGR - Salida: ' tipo_salida]);
            
        case 'Diagrama de Bode'
            figure('Name', ['Diagrama de Bode - Salida: ' tipo_salida]);
            bode(Sys_eval);
            grid on;
            margin(G); % Muestra márgenes de fase y ganancia
            
        case 'Criterio de Nyquist'
            figure('Name', ['Diagrama de Nyquist - Salida: ' tipo_salida]);
            nyquist(Sys_eval);
            grid on;
    end
   
app.MostrarelanlisisdeestabilidadButton.Enable =  'on';
app.ReiniciarButton.Enable  =  'on';
        end

        % Button pushed function: ReiniciarButton
        function ReiniciarButtonPushed(app, event)
    app.ROmegaEditField_2.Value= 0;
    app.LHEditField_2.Value=0;
    app.FriccinEditField_2.Value= 0;
    app.k_eradoverVsEditField_2.Value= 0;
    app.k_mNmoverAEditField_2.Value= 0;
    app.Jkgm2EditField_2.Value= 0;
    app.MagnituddelVoltajeVEditField.Value=0 ;
    app.GananciaproporcionalEditField.Value= 1;
    app.NmeradordelaretroalimentacinEditField_2.Value= 1;
    app.DenominadordelaretroalimentacinEditField_2= 1;
        end

        % Button pushed function: SimularcontrolButton
        function SimularcontrolButtonPushed(app, event)
            app.SimularcontrolButton.Enable= 'off';
            app.ReiniciarButton_2.Enable='off';

            % --- 1. PARÁMETROS FIJOS DEL MOTOR (Caracterización) ---
    ke = 0.8867;
    kt = 0.41675;
    J  = 0.01639;
    R  = 6;
    
    % --- 2. LECTURA DE ENTRADAS DEL USUARIO ---
    Kp  = app.K_pEditField.Value;
    Ki  = app.K_iEditField.Value;
    Kd  = app.K_dEditField.Value;
    Ref = app.VelocidaddeseadaradsEditField.Value; % Referencia de velocidad (Set point)
    
    % Validar que la referencia no sea cero o negativa (opcional, dependiendo de tu diseño)
    if Ref <= 0
        errordlg('Por favor, ingresa una velocidad deseada mayor a 0.', 'Error');
         app.SimularcontrolButton.Enable= 'on';
            app.ReiniciarButton_2.Enable='on';
        return;
    end

    % --- 3. MODELADO DE LA PLANTA G(s) ---
    % Usando la fórmula de la imagen:
    % Numerador: ke / (R*J)
    % Denominador: s + (ke*kt)/(R*J)
    
    num_G = ke / (R * J);
    den_G = [1,  (ke * kt) / (R * J)];
    G = tf(num_G, den_G);

    % --- 4. MODELADO DEL CONTROLADOR C(s) Y LAZO CERRADO ---
    % Construimos el PID usando la función nativa de MATLAB
    C = pid(Kp, Ki, Kd);
    
    % Sistema en Lazo Cerrado T(s) = C(s)G(s) / (1 + C(s)G(s))
    % Asumimos retroalimentación unitaria (el sensor lee la velocidad tal cual)
    T = feedback(C * G, 1);

    % --- 5. SIMULACIÓN DINÁMICA ---
    % Simulamos la respuesta al escalón multiplicada por la referencia
    [y_out, t_out] = step(Ref * T);

    % --- 6. GRAFICACIÓN ---
    % Cambia 'app.UIAxes' por el nombre de tu gráfica (ej. app.res o app.GraficaAxes)
    cla(app.UIAxes);
    hold(app.UIAxes, 'on');
    
    % Graficar la respuesta del motor
    plot(app.UIAxes, t_out, y_out, 'b-', 'LineWidth', 2);
    
    % Graficar la línea de Referencia (Set point)
    plot(app.UIAxes, [0, t_out(end)], [Ref, Ref], 'r--', 'LineWidth', 1.5);
    
    % Configuración de la gráfica
    grid(app.UIAxes, 'on');
    title(app.UIAxes, 'Respuesta del Sistema con Control');
    xlabel(app.UIAxes, 'Tiempo [s]');
    ylabel(app.UIAxes, 'Velocidad [rad/s]');
    legend(app.UIAxes, 'Velocidad Medida', 'Velocidad Deseada', 'Location', 'best');
    
    % Opcional: Mostrar datos clave en la gráfica (Sobreimpulso y Tiempo de estabilización)
    info = stepinfo(T);
    texto_info = sprintf('Tiempo Establ.: %.2f s\nSobreimpulso: %.1f %%', ...
                         info.SettlingTime, info.Overshoot);
                         
    % Colocar el texto en la esquina inferior derecha
    text(app.UIAxes, t_out(end)*0.6, Ref*0.2, texto_info, ...
         'FontSize', 10, 'BackgroundColor', 'w', 'EdgeColor', 'k');

    hold(app.UIAxes, 'off');
    
    % --- 7. REACTIVAR BOTONES ---
     app.SimularcontrolButton.Enable= 'on';
            app.ReiniciarButton_2.Enable='on';

        end

        % Button pushed function: ReiniciarButton_2
        function ReiniciarButton_2Pushed(app, event)
            app.K_dEditField.Value=0;
            app.K_iEditField.Value=0;
            app.K_pEditField.Value=0;
            app.VelocidaddeseadaradsEditField.Value=0;
            cla(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InicioTab = uitab(app.TabGroup);
            app.InicioTab.Title = 'Inicio';
            app.UniversidadNacionalAutnomadeMxicoFacultaddeingenieraLaboratorioVirtualdeControlautomticoLabel = uilabel(app.InicioTab);
            app.UniversidadNacionalAutnomadeMxicoFacultaddeingenieraLaboratorioVirtualdeControlautomticoLabel.Interpreter = 'latex';
            app.UniversidadNacionalAutnomadeMxicoFacultaddeingenieraLaboratorioVirtualdeControlautomticoLabel.Text = {'Universidad Nacional Autónoma de México '; ''; 'Facultad de ingeniería '; ''; 'Laboratorio Virtual de Control automático '};
            app.Image = uiimage(app.InicioTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'escudounam_negro.jpg');
            app.Image2 = uiimage(app.InicioTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'escudofi_negro.jpg');
            app.Image3 = uiimage(app.InicioTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2026-03-22 135115.png');
            app.ElsistemaestudiadoenestesimuladorcorrespondealmotordecorrientecontinuaUnsistemahibridocompuestoporunsistemamecanicoyunelectricoampliamenteestudiadoenlateoradecontrolendondeReslaresistenciadelaarmaduraLlainductanciadelaarmaduraJelmomentodeinerciadelrotorbelcoeficientedefriccinviscosak_tconstantedetorquek_econstantedefuerzacontraelectromotrizLabel = uilabel(app.InicioTab);
            app.ElsistemaestudiadoenestesimuladorcorrespondealmotordecorrientecontinuaUnsistemahibridocompuestoporunsistemamecanicoyunelectricoampliamenteestudiadoenlateoradecontrolendondeReslaresistenciadelaarmaduraLlainductanciadelaarmaduraJelmomentodeinerciadelrotorbelcoeficientedefriccinviscosak_tconstantedetorquek_econstantedefuerzacontraelectromotrizLabel.Interpreter = 'latex';
            app.ElsistemaestudiadoenestesimuladorcorrespondealmotordecorrientecontinuaUnsistemahibridocompuestoporunsistemamecanicoyunelectricoampliamenteestudiadoenlateoradecontrolendondeReslaresistenciadelaarmaduraLlainductanciadelaarmaduraJelmomentodeinerciadelrotorbelcoeficientedefriccinviscosak_tconstantedetorquek_econstantedefuerzacontraelectromotrizLabel.Text = {'El sistema estudiado en este simulador corresponde al motor de corriente continua.'; 'Un sistema hibrido compuesto por un sistema mecanico y un electrico ampliamente estudiado en la teoría de control'; 'en donde R es la resistencia de la armadura, L la inductancia de la armadura, J el momento'; 'de inercia del rotor, b el coeficiente de fricción viscosa, $k_t$ constante de torque, $k_e$ constante'; 'de fuerza contraelectromotriz'};
            app.ParanavegaratravesdeestesimuladorsolohagaclickenlapartesuperiorseleccionandolaprcticaquedesearealizarLabel = uilabel(app.InicioTab);
            app.ParanavegaratravesdeestesimuladorsolohagaclickenlapartesuperiorseleccionandolaprcticaquedesearealizarLabel.Text = 'Para navegar a traves de este simulador solo haga click en la parte superior seleccionando la práctica que desea realizar';
            app.Label = uilabel(app.InicioTab);
            app.Label.Text = '';
            app.EnestesimuladorpodrsconocerladinamicadelsistemaenlazoabiertolazocerradoydisearuncontroladorLabel = uilabel(app.InicioTab);
            app.EnestesimuladorpodrsconocerladinamicadelsistemaenlazoabiertolazocerradoydisearuncontroladorLabel.Interpreter = 'latex';
            app.EnestesimuladorpodrsconocerladinamicadelsistemaenlazoabiertolazocerradoydisearuncontroladorLabel.Text = 'En este simulador podrás conocer la dinamica del sistema en lazo abierto, lazo cerrado y diseñar un controlador ';
            app.Prctica1Tab = uitab(app.TabGroup);
            app.Prctica1Tab.Title = 'Práctica 1';
            app.res = uiaxes(app.Prctica1Tab);
            title(app.res, 'Respuesta del sistema')
            xlabel(app.res, 'Tiempo [s]')
            ylabel(app.res, 'Velocidad [rad/s]')
            zlabel(app.res, 'Z')
            app.Prctica1ModelomatemticoLabel = uilabel(app.Prctica1Tab);
            app.Prctica1ModelomatemticoLabel.Interpreter = 'latex';
            app.Prctica1ModelomatemticoLabel.Text = {'Práctica 1'; 'Modelo matemático '};
            app.LTILabel = uilabel(app.Prctica1Tab);
            app.LTILabel.Interpreter = 'latex';
            app.LTILabel.Text = {'\textbf{Representación LTI}'; ''; 'El sistema de ecuaciones:'; ''; '$\dot{x}=Ax+Bu'; ''; '$y=Cx+Du'; ''; 'Cuya representación matricial es:'; ''; '$\pmatrix{\dot{x_1}\cr \dot{x_2}}=\pmatrix{-{R\over L} & -{k_e \over L} \cr {k_t \over J & -{b \over J}}\pmatrix{x_1\cr x_2}+\pmatrix{{1 \over L} \cr 0}u$'; ''; '$y=\pmatrix{0 & 1}\pmatrix{x_1 \cr x_2}$'};
            app.ConlasconstantesResistenciadelemboninadoRInductanciadearmaduraLCoeficientedefriccinviscosabConstantedefuerzacontraelectromotrizk_eConstantedetorqueelectromecnicok_tylaconstantedeinerciaJAcontinuaciningreseelvalordelosparametrosylamagnituddelvoltajeLabel = uilabel(app.Prctica1Tab);
            app.ConlasconstantesResistenciadelemboninadoRInductanciadearmaduraLCoeficientedefriccinviscosabConstantedefuerzacontraelectromotrizk_eConstantedetorqueelectromecnicok_tylaconstantedeinerciaJAcontinuaciningreseelvalordelosparametrosylamagnituddelvoltajeLabel.Interpreter = 'latex';
            app.ConlasconstantesResistenciadelemboninadoRInductanciadearmaduraLCoeficientedefriccinviscosabConstantedefuerzacontraelectromotrizk_eConstantedetorqueelectromecnicok_tylaconstantedeinerciaJAcontinuaciningreseelvalordelosparametrosylamagnituddelvoltajeLabel.Text = {'Con las constantes:  '; 'Resistencia del emboninado ($R),'; 'Inductancia de armadura ($L),'; 'Coeficiente de fricción viscosa ($b),'; 'Constante de fuerza contraelectromotriz ($k_e),'; 'Constante de torque electromecánico ($k_t)'; 'y la constante de inercia ($J).'; ''; 'A continuación, ingrese el valor de los parametros y la magnitud del voltaje'};
            app.ROmegaLabel = uilabel(app.Prctica1Tab);
            app.ROmegaLabel.Interpreter = 'latex';
            app.ROmegaLabel.Text = 'R [$\Omega]';
            app.ROmegaEditField = uieditfield(app.Prctica1Tab, 'numeric');
            app.ROmegaEditField.Limits = [0 100];
            app.MagnituddelafuerzaNLabel = uilabel(app.Prctica1Tab);
            app.MagnituddelafuerzaNLabel.Interpreter = 'latex';
            app.MagnituddelafuerzaNLabel.Text = 'Magnitud del voltaje [V]';
            app.MagnituddelvoltajeVEditField = uieditfield(app.Prctica1Tab, 'numeric');
            app.MagnituddelvoltajeVEditField.Limits = [-100 100];
            app.LHEditFieldLabel = uilabel(app.Prctica1Tab);
            app.LHEditFieldLabel.Interpreter = 'latex';
            app.LHEditFieldLabel.Text = 'L [$H]';
            app.LHEditField = uieditfield(app.Prctica1Tab, 'numeric');
            app.LHEditField.Limits = [0 100];
            app.BLabel = uilabel(app.Prctica1Tab);
            app.BLabel.Interpreter = 'latex';
            app.BLabel.Text = 'B';
            app.FriccinEditField = uieditfield(app.Prctica1Tab, 'numeric');
            app.FriccinEditField.Limits = [0 100];
            app.SimularButton = uibutton(app.Prctica1Tab, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.ReiniciarsimulacinButton = uibutton(app.Prctica1Tab, 'push');
            app.ReiniciarsimulacinButton.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarsimulacinButtonPushed, true);
            app.ReiniciarsimulacinButton.Text = 'Reiniciar simulación';
            app.PropiedaddeFacultaddeIngenieraUNAMLabel = uilabel(app.Prctica1Tab);
            app.PropiedaddeFacultaddeIngenieraUNAMLabel.Text = 'Propiedad de Facultad de Ingeniería UNAM ';
            app.k_eradoverVsLabel = uilabel(app.Prctica1Tab);
            app.k_eradoverVsLabel.Interpreter = 'latex';
            app.k_eradoverVsLabel.Text = '$k_e [{rad \over {V*s}}]';
            app.k_eradoverVsEditField = uieditfield(app.Prctica1Tab, 'numeric');
            app.k_mNmoverALabel = uilabel(app.Prctica1Tab);
            app.k_mNmoverALabel.Interpreter = 'latex';
            app.k_mNmoverALabel.Text = '$k_m [{{N*m} \over A}]';
            app.k_mNmoverAEditField = uieditfield(app.Prctica1Tab, 'numeric');
            app.Jkgm2Label = uilabel(app.Prctica1Tab);
            app.Jkgm2Label.Interpreter = 'latex';
            app.Jkgm2Label.Text = '$J $$[kg*m^2]';
            app.Jkgm2EditField = uieditfield(app.Prctica1Tab, 'numeric');
            app.Prctica23Tab = uitab(app.TabGroup);
            app.Prctica23Tab.Title = 'Práctica 2 & 3';
            app.Prctica23AnlisisdeestabilidadLabel = uilabel(app.Prctica23Tab);
            app.Prctica23AnlisisdeestabilidadLabel.Interpreter = 'latex';
            app.Prctica23AnlisisdeestabilidadLabel.Text = {'Práctica 2 & 3 '; 'Análisis de estabilidad '};
            app.LTILabel_2 = uilabel(app.Prctica23Tab);
            app.LTILabel_2.Interpreter = 'latex';
            app.LTILabel_2.Text = {'Para la velocidad como salida:'; '${\Omega(s) \over U(s)} = {k_e \over {(R + Ls)(Js + b) + k_e k_t}} '};
            app.MostrarelanlisisdeestabilidadButton = uibutton(app.Prctica23Tab, 'push');
            app.MostrarelanlisisdeestabilidadButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarelanlisisdeestabilidadButtonPushed, true);
            app.MostrarelanlisisdeestabilidadButton.Text = 'Mostrar el análisis de estabilidad';
            app.ReiniciarButton = uibutton(app.Prctica23Tab, 'push');
            app.ReiniciarButton.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButtonPushed, true);
            app.ReiniciarButton.Text = 'Reiniciar ';
            app.PropiedaddeFacultaddeIngenieraUNAMLabel_2 = uilabel(app.Prctica23Tab);
            app.PropiedaddeFacultaddeIngenieraUNAMLabel_2.Text = 'Propiedad de Facultad de Ingeniería UNAM ';
            app.AcontinuaciningreseelvalordelosparametrosylamagnituddelvoltajeLabel = uilabel(app.Prctica23Tab);
            app.AcontinuaciningreseelvalordelosparametrosylamagnituddelvoltajeLabel.Interpreter = 'latex';
            app.AcontinuaciningreseelvalordelosparametrosylamagnituddelvoltajeLabel.Text = 'A continuación, ingrese el valor de los parametros y la magnitud del voltaje';
            app.MagnituddelVoltajeVEditFieldLabel = uilabel(app.Prctica23Tab);
            app.MagnituddelVoltajeVEditFieldLabel.Text = 'Magnitud del Voltaje [V]';
            app.MagnituddelVoltajeVEditField = uieditfield(app.Prctica23Tab, 'numeric');
            app.MagnituddelVoltajeVEditField.Limits = [-100 100];
            app.SalidadelsistemaLabel = uilabel(app.Prctica23Tab);
            app.SalidadelsistemaLabel.Text = 'Salida del sistema:';
            app.SalidadelsistemaDropDown = uidropdown(app.Prctica23Tab);
            app.SalidadelsistemaDropDown.Items = {'Posición ', 'Velocidad'};
            app.SalidadelsistemaDropDown.Value = 'Posición ';
            app.AnlisisporDropDownLabel = uilabel(app.Prctica23Tab);
            app.AnlisisporDropDownLabel.Text = 'Análisis por:';
            app.AnlisisporDropDown = uidropdown(app.Prctica23Tab);
            app.AnlisisporDropDown.Items = {'LGR', 'Diagrama de Bode', 'Criterio de Nyquist'};
            app.AnlisisporDropDown.Value = 'LGR';
            app.textbfLasexpresionesquedescribenlafuncindetransferenciadelsistemateniendocomosalidaposicinyvelocidadsonlassiguientesLabel = uilabel(app.Prctica23Tab);
            app.textbfLasexpresionesquedescribenlafuncindetransferenciadelsistemateniendocomosalidaposicinyvelocidadsonlassiguientesLabel.Interpreter = 'latex';
            app.textbfLasexpresionesquedescribenlafuncindetransferenciadelsistemateniendocomosalidaposicinyvelocidadsonlassiguientesLabel.Text = '\textbf{Las expresiones que describen la función de transferencia del sistema, teniendo como salida posición y velocidad, son las siguientes:}';
            app.ParalaposicincomosalidaThetasoverUsk_eoversRLsJsbk_ek_tLabel = uilabel(app.Prctica23Tab);
            app.ParalaposicincomosalidaThetasoverUsk_eoversRLsJsbk_ek_tLabel.Interpreter = 'latex';
            app.ParalaposicincomosalidaThetasoverUsk_eoversRLsJsbk_ek_tLabel.Text = {'Para la posición como salida:'; '${\Theta(s) \over U(s)} = {k_e \over {s ( (R + Ls)(Js + b) + k_e k_t)}} '};
            app.GananciaproporcionalEditFieldLabel = uilabel(app.Prctica23Tab);
            app.GananciaproporcionalEditFieldLabel.Text = 'Ganancia proporcional';
            app.GananciaproporcionalEditField = uieditfield(app.Prctica23Tab, 'numeric');
            app.GananciaproporcionalEditField.Limits = [1 Inf];
            app.GananciaproporcionalEditField.Value = 1;
            app.ROmegaEditField_2Label = uilabel(app.Prctica23Tab);
            app.ROmegaEditField_2Label.Interpreter = 'latex';
            app.ROmegaEditField_2Label.Text = 'R [$\Omega]';
            app.ROmegaEditField_2 = uieditfield(app.Prctica23Tab, 'numeric');
            app.ROmegaEditField_2.Limits = [0 100];
            app.LHEditField_2Label = uilabel(app.Prctica23Tab);
            app.LHEditField_2Label.Interpreter = 'latex';
            app.LHEditField_2Label.Text = 'L [$H]';
            app.LHEditField_2 = uieditfield(app.Prctica23Tab, 'numeric');
            app.LHEditField_2.Limits = [0 100];
            app.BLabel_2 = uilabel(app.Prctica23Tab);
            app.BLabel_2.Interpreter = 'latex';
            app.BLabel_2.Text = 'B';
            app.FriccinEditField_2 = uieditfield(app.Prctica23Tab, 'numeric');
            app.FriccinEditField_2.Limits = [0 100];
            app.k_eradoverVsEditField_2Label = uilabel(app.Prctica23Tab);
            app.k_eradoverVsEditField_2Label.Interpreter = 'latex';
            app.k_eradoverVsEditField_2Label.Text = '$k_e [{rad \over {V*s}}]';
            app.k_eradoverVsEditField_2 = uieditfield(app.Prctica23Tab, 'numeric');
            app.k_mNmoverAEditField_2Label = uilabel(app.Prctica23Tab);
            app.k_mNmoverAEditField_2Label.Interpreter = 'latex';
            app.k_mNmoverAEditField_2Label.Text = '$k_m [{{N*m} \over A}]';
            app.k_mNmoverAEditField_2 = uieditfield(app.Prctica23Tab, 'numeric');
            app.Jkgm2EditField_2Label = uilabel(app.Prctica23Tab);
            app.Jkgm2EditField_2Label.Interpreter = 'latex';
            app.Jkgm2EditField_2Label.Text = '$J $$[kg*m^2]';
            app.Jkgm2EditField_2 = uieditfield(app.Prctica23Tab, 'numeric');
            app.NmeradordelaretroalimentacinEditField_2Label = uilabel(app.Prctica23Tab);
            app.NmeradordelaretroalimentacinEditField_2Label.Text = 'Númerador de la retroalimentación';
            app.NmeradordelaretroalimentacinEditField_2 = uieditfield(app.Prctica23Tab, 'text');
            app.NmeradordelaretroalimentacinEditField_2.Value = '1';
            app.DenominadordelaretroalimentacinEditField_2Label = uilabel(app.Prctica23Tab);
            app.DenominadordelaretroalimentacinEditField_2Label.Text = 'Denominador de la retroalimentación';
            app.DenominadordelaretroalimentacinEditField_2 = uieditfield(app.Prctica23Tab, 'text');
            app.DenominadordelaretroalimentacinEditField_2.Value = '1';
            app.Prctica4Tab = uitab(app.TabGroup);
            app.Prctica4Tab.Title = 'Práctica 4';
            app.UIAxes = uiaxes(app.Prctica4Tab);
            title(app.UIAxes, 'Respuesta del sistema con control')
            xlabel(app.UIAxes, 'Tiempo[s]')
            ylabel(app.UIAxes, 'Velocidad [rad/s]')
            zlabel(app.UIAxes, 'Z')
            app.Prctica4DiseodecontroladoresPIDLabel = uilabel(app.Prctica4Tab);
            app.Prctica4DiseodecontroladoresPIDLabel.Interpreter = 'latex';
            app.Prctica4DiseodecontroladoresPIDLabel.Text = {'Práctica 4'; 'Diseño de controladores PID'};
            app.K_pLabel = uilabel(app.Prctica4Tab);
            app.K_pLabel.Interpreter = 'latex';
            app.K_pLabel.Text = '$K_p';
            app.K_pEditField = uieditfield(app.Prctica4Tab, 'numeric');
            app.K_pEditField.Limits = [0 50];
            app.K_iLabel = uilabel(app.Prctica4Tab);
            app.K_iLabel.Interpreter = 'latex';
            app.K_iLabel.Text = '$K_i';
            app.K_iEditField = uieditfield(app.Prctica4Tab, 'numeric');
            app.K_iEditField.Limits = [0 100];
            app.K_dLabel = uilabel(app.Prctica4Tab);
            app.K_dLabel.Interpreter = 'latex';
            app.K_dLabel.Text = '$K_d';
            app.K_dEditField = uieditfield(app.Prctica4Tab, 'numeric');
            app.K_dEditField.Limits = [0 50];
            app.SimularcontrolButton = uibutton(app.Prctica4Tab, 'push');
            app.SimularcontrolButton.ButtonPushedFcn = createCallbackFcn(app, @SimularcontrolButtonPushed, true);
            app.SimularcontrolButton.Text = 'Simular control';
            app.ReiniciarButton_2 = uibutton(app.Prctica4Tab, 'push');
            app.ReiniciarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButton_2Pushed, true);
            app.ReiniciarButton_2.Text = 'Reiniciar';
            app.ElmotorDCbajoestudioenestaprcticaeselGM25370HasidocaracterizadoexperimentalmentedespreciandolainductanciaylafriccinobteniendolossiguientesparametrosylafuncindetransferenciaLabel = uilabel(app.Prctica4Tab);
            app.ElmotorDCbajoestudioenestaprcticaeselGM25370HasidocaracterizadoexperimentalmentedespreciandolainductanciaylafriccinobteniendolossiguientesparametrosylafuncindetransferenciaLabel.Interpreter = 'latex';
            app.ElmotorDCbajoestudioenestaprcticaeselGM25370HasidocaracterizadoexperimentalmentedespreciandolainductanciaylafriccinobteniendolossiguientesparametrosylafuncindetransferenciaLabel.Text = {'El motor DC bajo estudio en esta práctica es el GM25-370 '; 'Ha sido caracterizado experimentalmente '; 'despreciando la inductancia y la fricción'; ' obteniendo los siguientes '; 'parametros y la función de transferencia:'};
            app.k_e088671k_t041675J001639R6Label = uilabel(app.Prctica4Tab);
            app.k_e088671k_t041675J001639R6Label.Interpreter = 'latex';
            app.k_e088671k_t041675J001639R6Label.Text = {'$k_e = 0.88671'; '$k_t = 0.41675'; 'J = 0.01639'; 'R = 6$'};
            app.Label_2 = uilabel(app.Prctica4Tab);
            app.Label_2.Text = '';
            app.OmegasoverUsk_eoverRJoversk_ek_toverRJLabel = uilabel(app.Prctica4Tab);
            app.OmegasoverUsk_eoverRJoversk_ek_toverRJLabel.Interpreter = 'latex';
            app.OmegasoverUsk_eoverRJoversk_ek_toverRJLabel.Text = '${\Omega(s) \over U(s)} = {{k_e \over RJ} \over {s+ {{k_e k_t} \over {RJ}}}';
            app.AcontinuacinpuedesdiseartucontroladoryobservarlarespuestadelsistemaLabel = uilabel(app.Prctica4Tab);
            app.AcontinuacinpuedesdiseartucontroladoryobservarlarespuestadelsistemaLabel.Interpreter = 'latex';
            app.AcontinuacinpuedesdiseartucontroladoryobservarlarespuestadelsistemaLabel.Text = 'A continuación puedes diseñar tu controlador y observar la respuesta del sistema';
            app.VelocidaddeseadaradsEditFieldLabel = uilabel(app.Prctica4Tab);
            app.VelocidaddeseadaradsEditFieldLabel.Interpreter = 'latex';
            app.VelocidaddeseadaradsEditFieldLabel.Text = 'Velocidad deseada [rad/s]';
            app.VelocidaddeseadaradsEditField = uieditfield(app.Prctica4Tab, 'numeric');
            app.VelocidaddeseadaradsEditField.Limits = [0 14];
            app.PropiedaddeFacultaddeIngenieraUNAMLabel_3 = uilabel(app.Prctica4Tab);
            app.PropiedaddeFacultaddeIngenieraUNAMLabel_3.Text = 'Propiedad de Facultad de Ingeniería UNAM ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Practicas_de_motorDC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end